classdef exp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        feel_1                  matlab.ui.control.Button
        feel_2                  matlab.ui.control.Button
        feel_3                  matlab.ui.control.Button
        FeeltexturenumberLabel  matlab.ui.control.Label
        pick_1                  matlab.ui.control.Button
        pick_2                  matlab.ui.control.Button
        pick_3                  matlab.ui.control.Button
        PicktheoddtextureoutofthethreeLabel  matlab.ui.control.Label
        next_texture            matlab.ui.control.Button
        setlabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        enter = 0;
        texture_final = 0;
        textures_felt = 0;
        feel_texture = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: feel_1
        function feel_1ButtonPushed(app, event)
            app.feel_texture = 1;
            app.feel_1.BackgroundColor = [.5 .5 1];
            app.feel_2.BackgroundColor = [1 1 1]*.96;
            app.feel_3.BackgroundColor = [1 1 1]*.96;
            app.textures_felt = app.textures_felt + 1;
        end

        % Button pushed function: feel_2
        function feel_2ButtonPushed(app, event)
            app.feel_texture = 2;
            app.feel_2.BackgroundColor = [.5 .5 1];
            app.feel_1.BackgroundColor = [1 1 1]*.96;
            app.feel_3.BackgroundColor = [1 1 1]*.96;
            app.textures_felt = app.textures_felt + 1;
        end

        % Button pushed function: feel_3
        function feel_3ButtonPushed(app, event)
            app.feel_texture = 3;
            app.feel_3.BackgroundColor = [.5 .5 1];
            app.feel_1.BackgroundColor = [1 1 1]*.96;
            app.feel_2.BackgroundColor = [1 1 1]*.96;
            app.textures_felt = app.textures_felt + 1;
        end

        % Button pushed function: pick_1
        function pick_1ButtonPushed(app, event)
            app.pick_1.BackgroundColor = [.5 .5 1];
            app.pick_2.BackgroundColor = [1 1 1]*.96;
            app.pick_3.BackgroundColor = [1 1 1]*.96;
            app.texture_final = 1;
        end

        % Button pushed function: pick_2
        function pick_2ButtonPushed(app, event)
            app.pick_2.BackgroundColor = [.5 .5 1];
            app.pick_1.BackgroundColor = [1 1 1]*.96;
            app.pick_3.BackgroundColor = [1 1 1]*.96;
            app.texture_final = 2;
        end

        % Button pushed function: pick_3
        function pick_3ButtonPushed(app, event)
            app.pick_3.BackgroundColor = [.5 .5 1];
            app.pick_1.BackgroundColor = [1 1 1]*.96;
            app.pick_2.BackgroundColor = [1 1 1]*.96;
            app.texture_final = 3;
        end

        % Button pushed function: next_texture
        function next_textureButtonPushed(app, event)
            if app.texture_final == 0
                app.next_texture.Text = 'Select a texture';
                pause(1);
                app.next_texture.Text = 'next set ->';
            else
                if app.textures_felt == 0
                    app.next_texture.Text = 'Feel some textures first';
                    pause(1);
                    app.next_texture.Text = 'next set ->';
                else
                    app.enter = 1;
                    app.next_texture.BackgroundColor = [.5 .5 1];
                end
            end
              
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.feel_1 = uibutton(app.UIFigure, 'push');
            app.feel_1.ButtonPushedFcn = createCallbackFcn(app, @feel_1ButtonPushed, true);
            app.feel_1.Text = '1';
            app.feel_2 = uibutton(app.UIFigure, 'push');
            app.feel_2.ButtonPushedFcn = createCallbackFcn(app, @feel_2ButtonPushed, true);
            app.feel_2.Text = '2';
            app.feel_3 = uibutton(app.UIFigure, 'push');
            app.feel_3.ButtonPushedFcn = createCallbackFcn(app, @feel_3ButtonPushed, true);
            app.feel_3.Text = '3';
            app.FeeltexturenumberLabel = uilabel(app.UIFigure);
            app.FeeltexturenumberLabel.Text = 'Feel texture number:';
            app.pick_1 = uibutton(app.UIFigure, 'push');
            app.pick_1.ButtonPushedFcn = createCallbackFcn(app, @pick_1ButtonPushed, true);
            app.pick_1.Text = '1';
            app.pick_2 = uibutton(app.UIFigure, 'push');
            app.pick_2.ButtonPushedFcn = createCallbackFcn(app, @pick_2ButtonPushed, true);
            app.pick_2.Text = '2';
            app.pick_3 = uibutton(app.UIFigure, 'push');
            app.pick_3.ButtonPushedFcn = createCallbackFcn(app, @pick_3ButtonPushed, true);
            app.pick_3.Text = '3';
            app.PicktheoddtextureoutofthethreeLabel = uilabel(app.UIFigure);
            app.PicktheoddtextureoutofthethreeLabel.Text = 'Pick the odd texture out of the three:';
            app.next_texture = uibutton(app.UIFigure, 'push');
            app.next_texture.ButtonPushedFcn = createCallbackFcn(app, @next_textureButtonPushed, true);
            app.next_texture.Text = 'next set ->';
            app.setlabel = uilabel(app.UIFigure);
            app.setlabel.Text = 'set humber: 1/63';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end