classdef exp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        feel_1                         matlab.ui.control.Button
        FeeltexturenumberLabel         matlab.ui.control.Label
        next_texture                   matlab.ui.control.Button
        setlabel                       matlab.ui.control.Label
        RatethetexturesroughnessLabel  matlab.ui.control.Label
        b1                             matlab.ui.control.Button
        b2                             matlab.ui.control.Button
        b3                             matlab.ui.control.Button
        b4                             matlab.ui.control.Button
        b5                             matlab.ui.control.Button
        b6                             matlab.ui.control.Button
        b7                             matlab.ui.control.Button
        b8                             matlab.ui.control.Button
        b9                             matlab.ui.control.Button
        b0                             matlab.ui.control.Button
        bc                             matlab.ui.control.Button
        bp                             matlab.ui.control.Button
        editfield                      matlab.ui.control.EditField
    end


    properties (Access = public)
        enter = 0;
        texture_final = 0;
        textures_felt = 0;
        feel_texture = 0;
        total = 0;
        
        period = 0;
        mul = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: feel_1
        function feel_1ButtonPushed(app, event)
            app.textures_felt = 1;
            app.feel_1.BackgroundColor = [.5 .5 1];
        end

        % Callback function
        function feel_2ButtonPushed(app, event)
            
        end

        % Callback function
        function feel_3ButtonPushed(app, event)
            
        end

        % Callback function
        function pick_1ButtonPushed(app, event)
            
        end

        % Callback function
        function pick_2ButtonPushed(app, event)
            
        end

        % Callback function
        function pick_3ButtonPushed(app, event)
            
        end

        % Button pushed function: next_texture
        function next_textureButtonPushed(app, event)
            app.enter = 1;
            app.next_texture.BackgroundColor = [.5 .5 1];
        end

        % Button pushed function: bc
        function bcButtonPushed(app, event)
            app.editfield.Value = '0';
            app.total = 0;
            app.period = 0;
            app.mul = 1;
        end

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 1;
            else
                app.total = app.total + app.mul*1;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: bp
        function bpButtonPushed(app, event)
            app.period = 1;
        end

        % Button pushed function: b2
        function b2ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 2;
            else
                app.total = app.total + app.mul*.2;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b3
        function b3ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 3;
            else
                app.total = app.total + app.mul*.3;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b4
        function b4ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 4;
            else
                app.total = app.total + app.mul*.4;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b5
        function b5ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 5;
            else
                app.total = app.total + app.mul*.5;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b6
        function b6ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 6;
            else
                app.total = app.total + app.mul*.6;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b7
        function b7ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 7;
            else
                app.total = app.total + app.mul*.7;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b8
        function b8ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 8;
            else
                app.total = app.total + app.mul*.8;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b9
        function b9ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10 + 9;
            else
                app.total = app.total + app.mul*.9;
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end

        % Button pushed function: b0
        function b0ButtonPushed(app, event)
            if app.period == 0
                app.total = app.total*10;
            else
                app.mul = app.mul*.1;
            end
            app.editfield.Value = num2str(app.total);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.feel_1 = uibutton(app.UIFigure, 'push');
            app.feel_1.ButtonPushedFcn = createCallbackFcn(app, @feel_1ButtonPushed, true);
            app.feel_1.Text = 'press me';
            app.FeeltexturenumberLabel = uilabel(app.UIFigure);
            app.FeeltexturenumberLabel.Text = 'Feel texture number:';
            app.next_texture = uibutton(app.UIFigure, 'push');
            app.next_texture.ButtonPushedFcn = createCallbackFcn(app, @next_textureButtonPushed, true);
            app.next_texture.Text = 'next texture ->';
            app.setlabel = uilabel(app.UIFigure);
            app.setlabel.Text = 'texture number: 1/24';
            app.RatethetexturesroughnessLabel = uilabel(app.UIFigure);
            app.RatethetexturesroughnessLabel.Text = 'Rate the texture''s roughness';
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = '1';
            app.b2 = uibutton(app.UIFigure, 'push');
            app.b2.ButtonPushedFcn = createCallbackFcn(app, @b2ButtonPushed, true);
            app.b2.Text = '2';
            app.b3 = uibutton(app.UIFigure, 'push');
            app.b3.ButtonPushedFcn = createCallbackFcn(app, @b3ButtonPushed, true);
            app.b3.Text = '3';
            app.b4 = uibutton(app.UIFigure, 'push');
            app.b4.ButtonPushedFcn = createCallbackFcn(app, @b4ButtonPushed, true);
            app.b4.Text = '4';
            app.b5 = uibutton(app.UIFigure, 'push');
            app.b5.ButtonPushedFcn = createCallbackFcn(app, @b5ButtonPushed, true);
            app.b5.Text = '5';
            app.b6 = uibutton(app.UIFigure, 'push');
            app.b6.ButtonPushedFcn = createCallbackFcn(app, @b6ButtonPushed, true);
            app.b6.Text = '6';
            app.b7 = uibutton(app.UIFigure, 'push');
            app.b7.ButtonPushedFcn = createCallbackFcn(app, @b7ButtonPushed, true);
            app.b7.Text = '7';
            app.b8 = uibutton(app.UIFigure, 'push');
            app.b8.ButtonPushedFcn = createCallbackFcn(app, @b8ButtonPushed, true);
            app.b8.Text = '8';
            app.b9 = uibutton(app.UIFigure, 'push');
            app.b9.ButtonPushedFcn = createCallbackFcn(app, @b9ButtonPushed, true);
            app.b9.Text = '9';
            app.b0 = uibutton(app.UIFigure, 'push');
            app.b0.ButtonPushedFcn = createCallbackFcn(app, @b0ButtonPushed, true);
            app.b0.Text = '0';
            app.bc = uibutton(app.UIFigure, 'push');
            app.bc.ButtonPushedFcn = createCallbackFcn(app, @bcButtonPushed, true);
            app.bc.Text = 'CLEAR';
            app.bp = uibutton(app.UIFigure, 'push');
            app.bp.ButtonPushedFcn = createCallbackFcn(app, @bpButtonPushed, true);
            app.bp.Text = '.';
            app.editfield = uieditfield(app.UIFigure, 'text');
            app.editfield.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end